classdef Acne_Lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PleasetakeacloseuppictureofyouracnesLabel  matlab.ui.control.Label
        UpdateTotalScoreButton        matlab.ui.control.Button
        UploadimageButton             matlab.ui.control.Button
        TurnonCameraButton            matlab.ui.control.Button
        CumulativeScoreTextArea       matlab.ui.control.TextArea
        CumulativeScoreTextAreaLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        LocalScoreTextArea            matlab.ui.control.TextArea
        LocalScoreTextAreaLabel       matlab.ui.control.Label
        LocationTextArea              matlab.ui.control.TextArea
        LocationTextAreaLabel         matlab.ui.control.Label
        TypeofAcneTextArea            matlab.ui.control.TextArea
        TypeofAcneTextAreaLabel       matlab.ui.control.Label
        TakeapictureButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cam; % Camera
        typeAcne; % Prediction of the type of acne from network
        probsAcne; % Prediction accuracy for type of acne
        location; % Prediction of the location of Acne from network
        probsLoc; % Prediction accuracy for location of acne
        score; % Local score
        cumScore = 0; % Cumulative score
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: TurnonCameraButton, UIAxes
        function TurnonCameraButtonPushed(app, event)
            % Open camera
            app.cam = webcam;
            frame = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes, 'image');
            preview(app.cam,im);
        end

        % Button pushed function: TakeapictureButton
        function TakeapictureButtonPushed(app, event)
            % Take an image of user's Acne
            clear app.cam
            delete myimage.png
            imwrite(snapshot(app.cam),'myimage.png');
            
            % Read the image
            I = imread('myimage.png');
            I = imresize(I, [224 224]);
            
            % Load the trained network
            load trainedNetwork_1.mat trainedNetwork_1 trainedNetwork_2; % load trained model
            whos trainedNetwork_1 trainedNetwork_2

            % Classify
            [app.typeAcne,app.probsAcne] = classify(trainedNetwork_1,I);
            [app.location,app.probsLoc] = classify(trainedNetwork_2,I);

            % Output on GUI
            AcneText = string(app.typeAcne) + ", " + num2str(100*max(app.probsAcne),3) + "%";
            disp(AcneText)
            LocText = string(app.location) + ", " + num2str(100*max(app.probsLoc),3) + "%";
            disp(LocText)

            app.TypeofAcneTextArea.Value = AcneText;
            app.LocationTextArea.Value = LocText;

            % Score the features
            keySet = {'Forehead','Chin','Nose','Cheek'};
            valueSet = [2 1 1 2];
            M1 = containers.Map(keySet,valueSet);
            factor = M1(string(app.location));
            disp(factor)

            keySet = {'Closed_Comedo','Cystic','Excoriated','Milia','Open_Comedo','Perioral_Dermatitis','Pustular','Rosacea', 'Scar'};
            valueSet = [1 2 3 1 1 3 4 4 2];
            M2 = containers.Map(keySet,valueSet);
            severity = M2(string(app.typeAcne));
            disp(severity)

            % Calculate the local score
            app.score = factor * severity;
            disp(class(app.score))
            app.LocalScoreTextArea.Value = string(app.score);

            % Calculate the cumulative score
            disp(app.cumScore)
            disp(class(app.cumScore))
            app.cumScore = app.score + app.cumScore;
            disp("Cumulative score" + string(app.cumScore))

        end

        % Button pushed function: UploadimageButton
        function UploadimageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.png';'*.*'},'Select an image file');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                load trainedNetwork_1.mat trainedNetwork_1 trainedNetwork_2; % load trained model
                whos trainedNetwork_1 trainedNetwork_2
                I = imread(fullfile(path,file));
                I = imresize(I, [224 224]);

                [app.typeAcne,app.probsAcne] = classify(trainedNetwork_1,I);
                [app.location,app.probsLoc] = classify(trainedNetwork_2,I);

                AcneText = string(app.typeAcne) + ", " + num2str(100*max(app.probsAcne),3) + "%";
                disp(AcneText)
                LocText = string(app.location) + ", " + num2str(100*max(app.probsLoc),3) + "%";
                disp(LocText)

                app.TypeofAcneTextArea.Value = AcneText;
                app.LocationTextArea.Value = LocText;

                keySet = {'Forehead','Chin','Nose','Cheek'};
                valueSet = [2 1 1 2];
                M1 = containers.Map(keySet,valueSet);
                factor = M1(string(app.location));
                disp(factor)

                keySet = {'Closed_Comedo','Cystic','Excoriated','Milia','Open_Comedo','Perioral_Dermatitis','Pustular','Rosacea', 'Scar'};
                valueSet = [1 2 3 1 1 3 4 4 2];
                M2 = containers.Map(keySet,valueSet);
                severity = M2(string(app.typeAcne));
                disp(severity)

                app.score = factor * severity;
                disp(class(app.score))
                app.LocalScoreTextArea.Value = string(app.score);

                disp(app.cumScore)
                disp(class(app.cumScore))
                app.cumScore = app.score + app.cumScore;
                disp("Cumulative score" + string(app.cumScore))

            end
        end

        % Button pushed function: UpdateTotalScoreButton
        function UpdateTotalScoreButtonPushed(app, event)
            % update the cumulative score
            app.CumulativeScoreTextArea.Value = string(app.cumScore);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Camera Preview')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @TurnonCameraButtonPushed, true);
            app.TakeapictureButton = uibutton(app.UIFigure, 'push');
            app.TakeapictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakeapictureButtonPushed, true);
            app.TakeapictureButton.Text = 'Take a picture';
            app.TypeofAcneTextAreaLabel = uilabel(app.UIFigure);
            app.TypeofAcneTextAreaLabel.Text = 'Type of Acne';
            app.TypeofAcneTextArea = uitextarea(app.UIFigure);
            app.LocationTextAreaLabel = uilabel(app.UIFigure);
            app.LocationTextAreaLabel.Text = 'Location';
            app.LocationTextArea = uitextarea(app.UIFigure);
            app.LocalScoreTextAreaLabel = uilabel(app.UIFigure);
            app.LocalScoreTextAreaLabel.Text = 'Local Score';
            app.LocalScoreTextArea = uitextarea(app.UIFigure);
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '___________________________________';
            app.CumulativeScoreTextAreaLabel = uilabel(app.UIFigure);
            app.CumulativeScoreTextAreaLabel.Text = 'Cumulative Score';
            app.CumulativeScoreTextArea = uitextarea(app.UIFigure);
            app.CumulativeScoreTextArea.Value = {'0'};
            app.TurnonCameraButton = uibutton(app.UIFigure, 'push');
            app.TurnonCameraButton.ButtonPushedFcn = createCallbackFcn(app, @TurnonCameraButtonPushed, true);
            app.TurnonCameraButton.Text = 'Turn on Camera';
            app.UploadimageButton = uibutton(app.UIFigure, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButtonPushed, true);
            app.UploadimageButton.Text = 'Upload image';
            app.UpdateTotalScoreButton = uibutton(app.UIFigure, 'push');
            app.UpdateTotalScoreButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTotalScoreButtonPushed, true);
            app.UpdateTotalScoreButton.Text = {'Update Total Score'; ''};
            app.PleasetakeacloseuppictureofyouracnesLabel = uilabel(app.UIFigure);
            app.PleasetakeacloseuppictureofyouracnesLabel.Text = 'Please take a close-up picture of your acnes.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Acne_Lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end